classdef DeliaControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        COMcontroller                  matlab.ui.control.NumericEditField
        COMCEditFieldLabel             matlab.ui.control.Label
        SetPointSlider                 matlab.ui.control.Slider
        SetPointbrPressurebrbarSliderLabel  matlab.ui.control.Label
        ConnectPressure                matlab.ui.control.StateButton
        COMsensor                      matlab.ui.control.NumericEditField
        COMSEditFieldLabel             matlab.ui.control.Label
        PressureDisplay                matlab.ui.control.NumericEditField
        PressurebarEditFieldLabel      matlab.ui.control.Label
        PressureLabel                  matlab.ui.control.Label
        Pump2panel                     matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        COMpump2                       matlab.ui.control.NumericEditField
        COMEditField_3Label            matlab.ui.control.Label
        Pump2flow                      matlab.ui.control.NumericEditField
        FlowratebrmLminsup1supEditField_2Label  matlab.ui.control.Label
        Pump2start                     matlab.ui.control.Button
        Pump2PLL                       matlab.ui.control.NumericEditField
        PressureLowerLimitbarEditField_2Label  matlab.ui.control.Label
        Pump2PUL                       matlab.ui.control.NumericEditField
        PressureUpperLimitbarEditField_3Label  matlab.ui.control.Label
        ConnectPump2                   matlab.ui.control.StateButton
        Pump2Label                     matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        T3CEditField                   matlab.ui.control.NumericEditField
        T3CEditFieldLabel              matlab.ui.control.Label
        T2CEditField                   matlab.ui.control.NumericEditField
        T2CEditFieldLabel              matlab.ui.control.Label
        Button_4                       matlab.ui.control.StateButton
        COMtemp                        matlab.ui.control.NumericEditField
        COMEditField_5Label            matlab.ui.control.Label
        TemperatureLabel               matlab.ui.control.Label
        V11JoeMarsdenNov2026Label_2    matlab.ui.control.Label
        Pump1panel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        COMpump1                       matlab.ui.control.NumericEditField
        COMEditField_2Label            matlab.ui.control.Label
        Pump1start                     matlab.ui.control.Button
        Pump1flow                      matlab.ui.control.NumericEditField
        FlowratemLminLabel             matlab.ui.control.Label
        Pump1PLL                       matlab.ui.control.NumericEditField
        PressureLowerLimitbarEditFieldLabel  matlab.ui.control.Label
        Pump1PUL                       matlab.ui.control.NumericEditField
        PressureUpperLimitbarEditFieldLabel  matlab.ui.control.Label
        ConnectPump1                   matlab.ui.control.StateButton
        Pump1Label                     matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ReflectedPowerWEditField_2     matlab.ui.control.NumericEditField
        ReflectedPowerWEditField_2Label  matlab.ui.control.Label
        ForwardPowerWEditField_2       matlab.ui.control.NumericEditField
        ForwardPowerWEditField_2Label  matlab.ui.control.Label
        Stub3Spinner                   matlab.ui.control.Spinner
        Stub3SpinnerLabel              matlab.ui.control.Label
        Stub2Spinner                   matlab.ui.control.Spinner
        Stub2SpinnerLabel              matlab.ui.control.Label
        Stub1Spinner                   matlab.ui.control.Spinner
        Stub1SpinnerLabel              matlab.ui.control.Label
        COMtuner                       matlab.ui.control.NumericEditField
        COMEditField_7Label            matlab.ui.control.Label
        OneStepTuneButton              matlab.ui.control.Button
        AllStubsHomePositionButton     matlab.ui.control.Button
        Button_3                       matlab.ui.control.StateButton
        AutotunerLabel                 matlab.ui.control.Label
        StubGraph                      matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        COMgen                         matlab.ui.control.NumericEditField
        COMEditFieldLabel              matlab.ui.control.Label
        ReflectedPowerWEditField       matlab.ui.control.NumericEditField
        ReflectedPowerWEditFieldLabel  matlab.ui.control.Label
        ForwardPowerWEditField         matlab.ui.control.NumericEditField
        ForwardPowerWEditFieldLabel    matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        SetPowerWEditField             matlab.ui.control.NumericEditField
        SetPowerWEditFieldLabel        matlab.ui.control.Label
        Button_5                       matlab.ui.control.StateButton
        InstrumentDropDown             matlab.ui.control.DropDown
        InstrumentDropDownLabel        matlab.ui.control.Label
        GeneratorLabel                 matlab.ui.control.Label
        DeliaMicrowaveFlowPlatformControlStationLabel  matlab.ui.control.Label
        MonitorTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_13                       matlab.ui.container.Panel
        GridLayout9_2                  matlab.ui.container.GridLayout
        EditField                      matlab.ui.control.EditField
        StatusreadyLabel_2             matlab.ui.control.Label
        BeginMonitoringButton          matlab.ui.control.Button
        Panel_12                       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        LogFileCheckBox                matlab.ui.control.CheckBox
        PressureCheckBox               matlab.ui.control.CheckBox
        TemperatureCheckBox            matlab.ui.control.CheckBox
        PowerCheckBox                  matlab.ui.control.CheckBox
        IncludeLabel                   matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DataDirectoryLabel             matlab.ui.control.Label
        ChooseDirectoryButton          matlab.ui.control.Button
        StopMWbrPowerButton            matlab.ui.control.Button
        Panel_10                       matlab.ui.container.Panel
        GridLayout8_3                  matlab.ui.container.GridLayout
        UIAxes_3                       matlab.ui.control.UIAxes
        Panel_9                        matlab.ui.container.Panel
        GridLayout8_2                  matlab.ui.container.GridLayout
        UIAxes_2                       matlab.ui.control.UIAxes
        Panel_8                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        DeliaMicrowaveFlowPlatformControlStationLabel_2  matlab.ui.control.Label
        V11JoeMarsdenNov2026Label      matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        PIDTab                         matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        b % bar chart for stub positions

        % Equipment connect status
        EPRconnected = 0 % 0 for disconnected, 1 for connected
        sensorconnected = 0 % 0 for disconnected, 1 for connected
        pump1connected = 0 % 0 for disconnected, 1 for connected
        pump2connected = 0 % 0 for disconnected, 1 for connected

        % Equipment objects
        EPRobj = [] % Object placeholder for EPC
        sensorObj = [] % Placeholder for sensor
        Pump1 = [] % placeholder for pump 1
        Pump2 = [] % placeholder for pump 2

        % Timers
        PressureTimer timer % Used to get pressure data
    end
    
    methods (Access = private)
        
        
        function pollPressure(app)

            voltage = sensor_read_voltage(app.sensorObj)
            
            % Convert to pressure
            pressure = (voltage - 0.86)/(0.86*5 - 0.86)*40 + 1
            
            % Update label
            app.PressureDisplay.Text = string(round(pressure,0,"decimals")) + " bar"
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Plot autotuner graph
            app.StubGraph.Color = [0.15 0.15 0.15];
            yline(app.StubGraph,0,'-','','Color',[0.95 0.4 0.77]);
            yline(app.StubGraph,25,'-','','Color',[0.95 0.4 0.77]);

            app.PressureTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollPressure());
        end

        % Value changed function: Stub1Spinner
        function Stub1SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);
        end

        % Value changed function: Stub2Spinner
        function Stub2SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);
        end

        % Value changed function: Stub3Spinner
        function Stub3SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);            
        end

        % Value changed function: SetPointSlider
        function SetPointSliderValueChanged(app, event)
            % This slider sets the pressure. The values are approximate
            % until it is calibrated more precisely

            app.StatusLabel.Text = "Status: Changing pressure";

            value = app.SetPointSlider.Value;
            
            % Set the voltage on the EPC
            EPC_set_voltage(app.EPRobj,0,value);

            app.StatusLabel.Text = "Status: Ready";
        end

        % Value changed function: ConnectPressure
        function ConnectPressureValueChanged(app, event)
            value = app.ConnectPressure.Value;
            
            if value == 1

                % This button connects to BOTH the pressure sensor and the EPR.
                app.StatusLabel.Text = "Status: Connecting to pressure equipment";

                % Connect to Sensor
    
                % Run connect driver
                comsensor = app.COMsensor.Value;
                app.sensorObj = sensor_connect(comsensor);
    
                % Connect to EPR
    
                % Run connect driver
                comcontrol = app.COMcontroller.Value;
                app.EPRobj = EPC_connect(comcontrol);
    
                % Update status
                app.sensorconnected = 1;
                app.EPRconnected = 1;
    
                % Enable voltage slider if BOTH are connected
                if app.sensorconnected * app.EPCconnected == 1
                    app.SetPointSlider.Enable = "on";
                end
    
                % Begin timer for monitoring
                start(app.PressureTimer);
    
                % Change button to disconnect
                app.ConnectPressure.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % This button disconnects from BOTH the sensor and EPR
                app.StatusLabel.Text = "Status: Disconnecting from pressure equipment";

                % Disconnect from sensor 

                % Run disconnect driver
                sensor_disconnect(app.sensorObj)
                app.sensorconnected = 0;

                % Disconnect from EPR

                 % Run disconnect driver
                 EPC_disconnect(app.EPRobj)
                 app.EPRconnected = 0;

                 % Disable slider
                app.SetPointSlider.Enable = "off";
                                
                % Change button to connect
                app.ConnectPressure.Text = "Connect";

                % FUTURE Reset label to 1 bar?

                % Disable timer
                stop(app.PressureTimer);

                app.StatusLabel.Text = "Status: Ready";

            end
        end

        % Value changed function: ConnectPump1
        function ConnectPump1ValueChanged(app, event)
            value = app.ConnectPump1.Value;
            
            if value == 1

                % Connect to pump
                app.StatusLabel.Text = "Status: Connecting to Pump 1";

                % Run connect driver
                compump1 = app.COMpump1.Value;
                app.Pump1 = pumpLSconnect(compump1);

                % Update status
                app.pump1connected = 1;

                % Enable entry fields
                app.Pump1PUL.Enable = "on";
                app.Pump1PLL.Enable = "on";
                app.Pump1flow.Enable = "on";

                % Enable start button
                app.Pump1start.Enable = "on";

                % Change button to disconnect
                app.ConnectPump1.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % Disconnect from pump
                app.StatusLabel.Text = "Status: Disconnecting from Pump 1";

                % Run disconnect driver
                pumpLSdisconnect(app.Pump1);

                % Update status
                app.pump1connected = 0;

                % Disable entry fields
                app.Pump1PUL.Enable = "off";
                app.Pump1PLL.Enable = "off";
                app.Pump1flow.Enable = "off";

                % Disable start button
                app.Pump1start.Enable = "off";

                % Change button to connect
                app.ConnectPump1.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            end
        end

        % Value changed function: ConnectPump2
        function ConnectPump2ValueChanged(app, event)
            value = app.ConnectPump2.Value;
            
            if value == 1

                % Connect to pump
                app.StatusLabel.Text = "Connecting to Pump 2";

                % Run connect driver
                compump2 = app.COMpump2.Value;
                app.Pump2 = pumpLSconnect(compump2);

                % Update status
                app.pump2connected = 1;

                % Enable entry fields
                app.Pump2PUL.Enable = "on";
                app.Pump2PLL.Enable = "on";
                app.Pump2flow.Enable = "on";

                % Enable start button
                app.Pump2start.Enable = "on";

                % Change button to disconnect
                app.ConnectPump2.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % Disconnect from pump
                app.StatusLabel.Text = "Disconnecting from Pump 2";

                % Run disconnect driver
                pumpLSdisconnect(app.Pump2);

                % Update status
                app.pump2connected = 0;

                % Disable entry fields
                app.Pump2PUL.Enable = "off";
                app.Pump2PLL.Enable = "off";
                app.Pump2flow.Enable = "off";

                % Disable start button
                app.Pump2start.Enable = "off";

                % Change button to connect
                app.ConnectPump2.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlTab);
            app.DeliaMicrowaveFlowPlatformControlStationLabel = uilabel(app.GridLayout);
            app.DeliaMicrowaveFlowPlatformControlStationLabel.Text = 'Delia Microwave Flow Platform Control Station';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GeneratorLabel = uilabel(app.GridLayout2);
            app.GeneratorLabel.Text = 'Generator';
            app.InstrumentDropDownLabel = uilabel(app.GridLayout2);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.GridLayout2);
            app.InstrumentDropDown.Items = {'2 kW', '1 kW'};
            app.InstrumentDropDown.Value = '2 kW';
            app.Button_5 = uibutton(app.GridLayout2, 'state');
            app.Button_5.Text = 'Connect';
            app.SetPowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.SetPowerWEditFieldLabel.Text = 'Set Power / W';
            app.SetPowerWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SetPowerWEditField.Editable = 'off';
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.ForwardPowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.ForwardPowerWEditFieldLabel.Text = 'Forward Power / W';
            app.ForwardPowerWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ReflectedPowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.ReflectedPowerWEditFieldLabel.Text = 'Reflected Power / W';
            app.ReflectedPowerWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.COMEditFieldLabel = uilabel(app.GridLayout2);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMgen = uieditfield(app.GridLayout2, 'numeric');
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_5);
            app.StubGraph = uiaxes(app.GridLayout3);
            app.StubGraph.XLim = [0.5 3.5];
            app.StubGraph.YLim = [0 25];
            app.StubGraph.XLimitMethod = 'tight';
            app.StubGraph.YLimitMethod = 'tight';
            app.StubGraph.ZLimitMethod = 'tight';
            app.StubGraph.XTick = [1 2 3];
            app.StubGraph.YTick = [];
            app.StubGraph.ZTick = [];
            app.AutotunerLabel = uilabel(app.GridLayout3);
            app.AutotunerLabel.Text = 'Autotuner';
            app.Button_3 = uibutton(app.GridLayout3, 'state');
            app.Button_3.Text = 'Connect';
            app.AllStubsHomePositionButton = uibutton(app.GridLayout3, 'push');
            app.AllStubsHomePositionButton.Enable = 'off';
            app.AllStubsHomePositionButton.Text = 'All Stubs Home Position';
            app.OneStepTuneButton = uibutton(app.GridLayout3, 'push');
            app.OneStepTuneButton.Enable = 'off';
            app.OneStepTuneButton.Text = 'One-Step Tune';
            app.COMEditField_7Label = uilabel(app.GridLayout3);
            app.COMEditField_7Label.Text = 'COM';
            app.COMtuner = uieditfield(app.GridLayout3, 'numeric');
            app.Stub1SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub1SpinnerLabel.Text = 'Stub 1';
            app.Stub1Spinner = uispinner(app.GridLayout3);
            app.Stub1Spinner.Limits = [0 25];
            app.Stub1Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub1SpinnerValueChanged, true);
            app.Stub2SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub2SpinnerLabel.Text = 'Stub 2';
            app.Stub2Spinner = uispinner(app.GridLayout3);
            app.Stub2Spinner.Limits = [0 25];
            app.Stub2Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub2SpinnerValueChanged, true);
            app.Stub3SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub3SpinnerLabel.Text = 'Stub 3';
            app.Stub3Spinner = uispinner(app.GridLayout3);
            app.Stub3Spinner.Limits = [0 25];
            app.Stub3Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub3SpinnerValueChanged, true);
            app.ForwardPowerWEditField_2Label = uilabel(app.GridLayout3);
            app.ForwardPowerWEditField_2Label.Text = 'Forward Power / W';
            app.ForwardPowerWEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.ReflectedPowerWEditField_2Label = uilabel(app.GridLayout3);
            app.ReflectedPowerWEditField_2Label.Text = 'Reflected Power / W';
            app.ReflectedPowerWEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.Pump1panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Pump1panel);
            app.Pump1Label = uilabel(app.GridLayout5);
            app.Pump1Label.Text = 'Pump 1';
            app.ConnectPump1 = uibutton(app.GridLayout5, 'state');
            app.ConnectPump1.ValueChangedFcn = createCallbackFcn(app, @ConnectPump1ValueChanged, true);
            app.ConnectPump1.Text = 'Connect';
            app.PressureUpperLimitbarEditFieldLabel = uilabel(app.GridLayout5);
            app.PressureUpperLimitbarEditFieldLabel.Text = 'Pressure Upper Limit / bar';
            app.Pump1PUL = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1PUL.Editable = 'off';
            app.Pump1PUL.Value = 40;
            app.PressureLowerLimitbarEditFieldLabel = uilabel(app.GridLayout5);
            app.PressureLowerLimitbarEditFieldLabel.Text = 'Pressure Lower Limit / bar';
            app.Pump1PLL = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1PLL.Editable = 'off';
            app.Pump1PLL.Value = 1;
            app.FlowratemLminLabel = uilabel(app.GridLayout5);
            app.FlowratemLminLabel.Interpreter = 'html';
            app.FlowratemLminLabel.Text = 'Flowrate</br>/ mL min<sup>-1</sup>';
            app.Pump1flow = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1flow.Editable = 'off';
            app.Pump1flow.Value = 1;
            app.Pump1start = uibutton(app.GridLayout5, 'push');
            app.Pump1start.Enable = 'off';
            app.Pump1start.Text = 'Start';
            app.COMEditField_2Label = uilabel(app.GridLayout5);
            app.COMEditField_2Label.Text = 'COM';
            app.COMpump1 = uieditfield(app.GridLayout5, 'numeric');
            app.V11JoeMarsdenNov2026Label_2 = uilabel(app.GridLayout);
            app.V11JoeMarsdenNov2026Label_2.Text = 'V1.1 Joe Marsden Nov 2026';
            app.Panel_6 = uipanel(app.GridLayout);
            app.GridLayout4_2 = uigridlayout(app.Panel_6);
            app.TemperatureLabel = uilabel(app.GridLayout4_2);
            app.TemperatureLabel.Text = 'Temperature';
            app.COMEditField_5Label = uilabel(app.GridLayout4_2);
            app.COMEditField_5Label.Text = 'COM';
            app.COMtemp = uieditfield(app.GridLayout4_2, 'numeric');
            app.Button_4 = uibutton(app.GridLayout4_2, 'state');
            app.Button_4.Text = 'Connect';
            app.T2CEditFieldLabel = uilabel(app.GridLayout4_2);
            app.T2CEditFieldLabel.Text = 'T2 / °C';
            app.T2CEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.T3CEditFieldLabel = uilabel(app.GridLayout4_2);
            app.T3CEditFieldLabel.Text = 'T3 / °C';
            app.T3CEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.Pump2panel = uipanel(app.GridLayout);
            app.GridLayout5_2 = uigridlayout(app.Pump2panel);
            app.Pump2Label = uilabel(app.GridLayout5_2);
            app.Pump2Label.Text = 'Pump 2';
            app.ConnectPump2 = uibutton(app.GridLayout5_2, 'state');
            app.ConnectPump2.ValueChangedFcn = createCallbackFcn(app, @ConnectPump2ValueChanged, true);
            app.ConnectPump2.Text = 'Connect';
            app.PressureUpperLimitbarEditField_3Label = uilabel(app.GridLayout5_2);
            app.PressureUpperLimitbarEditField_3Label.Text = 'Pressure Upper Limit / bar';
            app.Pump2PUL = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2PUL.Editable = 'off';
            app.Pump2PUL.Value = 40;
            app.PressureLowerLimitbarEditField_2Label = uilabel(app.GridLayout5_2);
            app.PressureLowerLimitbarEditField_2Label.Text = 'Pressure Lower Limit / bar';
            app.Pump2PLL = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2PLL.Editable = 'off';
            app.Pump2PLL.Value = 1;
            app.Pump2start = uibutton(app.GridLayout5_2, 'push');
            app.Pump2start.Enable = 'off';
            app.Pump2start.Text = 'Start';
            app.FlowratebrmLminsup1supEditField_2Label = uilabel(app.GridLayout5_2);
            app.FlowratebrmLminsup1supEditField_2Label.Interpreter = 'html';
            app.FlowratebrmLminsup1supEditField_2Label.Text = 'Flowrate</br>/ mL min<sup>-1</sup>';
            app.Pump2flow = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2flow.Editable = 'off';
            app.Pump2flow.Value = 1;
            app.COMEditField_3Label = uilabel(app.GridLayout5_2);
            app.COMEditField_3Label.Text = 'COM';
            app.COMpump2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.PressureLabel = uilabel(app.GridLayout4);
            app.PressureLabel.Text = 'Pressure';
            app.PressurebarEditFieldLabel = uilabel(app.GridLayout4);
            app.PressurebarEditFieldLabel.Text = 'Pressure / bar';
            app.PressureDisplay = uieditfield(app.GridLayout4, 'numeric');
            app.COMSEditFieldLabel = uilabel(app.GridLayout4);
            app.COMSEditFieldLabel.Text = 'COM (S)';
            app.COMsensor = uieditfield(app.GridLayout4, 'numeric');
            app.COMsensor.Value = 18;
            app.ConnectPressure = uibutton(app.GridLayout4, 'state');
            app.ConnectPressure.ValueChangedFcn = createCallbackFcn(app, @ConnectPressureValueChanged, true);
            app.ConnectPressure.Text = 'Connect';
            app.SetPointbrPressurebrbarSliderLabel = uilabel(app.GridLayout4);
            app.SetPointbrPressurebrbarSliderLabel.Interpreter = 'html';
            app.SetPointbrPressurebrbarSliderLabel.Text = 'Set Point<br/>Pressure<br/>/ bar';
            app.SetPointSlider = uislider(app.GridLayout4);
            app.SetPointSlider.Limits = [0 30];
            app.SetPointSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.SetPointSlider.ValueChangedFcn = createCallbackFcn(app, @SetPointSliderValueChanged, true);
            app.SetPointSlider.Step = 1;
            app.SetPointSlider.Enable = 'off';
            app.COMCEditFieldLabel = uilabel(app.GridLayout4);
            app.COMCEditFieldLabel.Text = 'COM (C)';
            app.COMcontroller = uieditfield(app.GridLayout4, 'numeric');
            app.COMcontroller.Value = 19;
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status: ready';
            app.MonitorTab = uitab(app.TabGroup);
            app.MonitorTab.Title = 'Monitor';
            app.GridLayout7 = uigridlayout(app.MonitorTab);
            app.V11JoeMarsdenNov2026Label = uilabel(app.GridLayout7);
            app.V11JoeMarsdenNov2026Label.Text = 'V1.1 Joe Marsden Nov 2026';
            app.DeliaMicrowaveFlowPlatformControlStationLabel_2 = uilabel(app.GridLayout7);
            app.DeliaMicrowaveFlowPlatformControlStationLabel_2.Text = 'Delia Microwave Flow Platform Control Station';
            app.Panel_8 = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel_8);
            app.UIAxes = uiaxes(app.GridLayout8);
            xlabel(app.UIAxes, 'Time / min')
            ylabel(app.UIAxes, 'Power / W')
            zlabel(app.UIAxes, 'Z')
            app.Panel_9 = uipanel(app.GridLayout7);
            app.GridLayout8_2 = uigridlayout(app.Panel_9);
            app.UIAxes_2 = uiaxes(app.GridLayout8_2);
            xlabel(app.UIAxes_2, 'Time / min')
            ylabel(app.UIAxes_2, 'Temperature / °C')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_10 = uipanel(app.GridLayout7);
            app.GridLayout8_3 = uigridlayout(app.Panel_10);
            app.UIAxes_3 = uiaxes(app.GridLayout8_3);
            xlabel(app.UIAxes_3, 'Time / min')
            ylabel(app.UIAxes_3, 'Pressure / bar')
            zlabel(app.UIAxes_3, 'Z')
            app.StopMWbrPowerButton = uibutton(app.GridLayout7, 'push');
            app.StopMWbrPowerButton.Enable = 'off';
            app.StopMWbrPowerButton.Interpreter = 'html';
            app.StopMWbrPowerButton.Text = 'Stop MW<br/>Power';
            app.ChooseDirectoryButton = uibutton(app.GridLayout7, 'push');
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.Panel_11 = uipanel(app.GridLayout7);
            app.GridLayout9 = uigridlayout(app.Panel_11);
            app.DataDirectoryLabel = uilabel(app.GridLayout9);
            app.DataDirectoryLabel.Text = 'Data Directory';
            app.Panel_12 = uipanel(app.GridLayout7);
            app.GridLayout10 = uigridlayout(app.Panel_12);
            app.IncludeLabel = uilabel(app.GridLayout10);
            app.IncludeLabel.Text = 'Include';
            app.PowerCheckBox = uicheckbox(app.GridLayout10);
            app.PowerCheckBox.Text = 'Power';
            app.PowerCheckBox.Value = true;
            app.TemperatureCheckBox = uicheckbox(app.GridLayout10);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.TemperatureCheckBox.Value = true;
            app.PressureCheckBox = uicheckbox(app.GridLayout10);
            app.PressureCheckBox.Text = 'Pressure';
            app.PressureCheckBox.Value = true;
            app.LogFileCheckBox = uicheckbox(app.GridLayout10);
            app.LogFileCheckBox.Text = 'Log File';
            app.LogFileCheckBox.Value = true;
            app.BeginMonitoringButton = uibutton(app.GridLayout7, 'push');
            app.BeginMonitoringButton.Text = 'Begin Monitoring';
            app.StatusreadyLabel_2 = uilabel(app.GridLayout7);
            app.StatusreadyLabel_2.Text = 'Status: ready';
            app.Panel_13 = uipanel(app.GridLayout7);
            app.GridLayout9_2 = uigridlayout(app.Panel_13);
            app.EditField = uieditfield(app.GridLayout9_2, 'text');
            app.EditField.Value = 'Input File Name';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TextAreaLabel = uilabel(app.SettingsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.SettingsTab);
            app.TextArea.Value = {'Pressure calibration'; 'Plot homer or generator'; ''};
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'P&ID';
            app.GridLayout6 = uigridlayout(app.PIDTab);
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'PIDAPP.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeliaControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end